classdef forceplate_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ForceplateProcessingtoolMainUIFigure  matlab.ui.Figure
        Menu                        matlab.ui.container.Menu
        LoadfileMenu                matlab.ui.container.Menu
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        UIAxes3_2                   matlab.ui.control.UIAxes
        UITable                     matlab.ui.control.Table
        ColumnselectADropDownLabel  matlab.ui.control.Label
        ColumnselectADropDown       matlab.ui.control.DropDown
        ColumnselectBDropDownLabel  matlab.ui.control.Label
        ColumnselectBDropDown       matlab.ui.control.DropDown
        DataselectDropDownLabel     matlab.ui.control.Label
        DataselectDropDown          matlab.ui.control.DropDown
        CalculatepeaksButton        matlab.ui.control.Button
        SmoothAButton               matlab.ui.control.Button
        SmoothBButton               matlab.ui.control.Button
        ResetdataAButton            matlab.ui.control.Button
        ResetdataBButton            matlab.ui.control.Button
        maxthresholdSliderLabel     matlab.ui.control.Label
        maxthresholdSlider          matlab.ui.control.Slider
        minthresholdSliderLabel     matlab.ui.control.Label
        minthresholdSlider          matlab.ui.control.Slider
        maxthresholdSlider_2Label   matlab.ui.control.Label
        maxthresholdSlider_2        matlab.ui.control.Slider
        minthresholdSlider_2Label   matlab.ui.control.Label
        minthresholdSlider_2        matlab.ui.control.Slider
        ExtractDatapoints           matlab.ui.control.Button
        UIAxes4                     matlab.ui.control.UIAxes
        StartSlider                 matlab.ui.control.Slider
        JumpIntervalEditFieldLabel  matlab.ui.control.Label
        JumpIntervalEditField       matlab.ui.control.EditField
        RemoveoffsetButton          matlab.ui.control.Button
        RemoveoffsetButton_2        matlab.ui.control.Button
        ImpulseEditFieldLabel       matlab.ui.control.Label
        ImpulseEditField            matlab.ui.control.EditField
        HeightEditFieldLabel        matlab.ui.control.Label
        HeightEditField             matlab.ui.control.EditField
        HelpButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        HelpPopup % Description
    end
    
    methods (Access = private)
        
        function calculateHeight(app,FP_TOTAL, START_IND, OFF_GROUND, MED, JUMP_TIME, t)
            % CALCULATE THE IMPULSE
            % ---------------------
            % Calculate impulse momentum.
            % I = integral of Fz * t from start of jump to takeoff.
            
            START_IND = START_IND + 1;                                              % To prevent indexing of 0 in integral 
            
            IMP_MOM = 0;
            
            for i = START_IND : OFF_GROUND
               IMP_MOM = IMP_MOM + (((FP_TOTAL(i) - MED)*9.81)*(t(i) - t(i - 1)));
            end
            
            % CALCULATE VELOCITY
            % ------------------
            VEL = IMP_MOM/MED;
            
            % CALCULATE HEIGHT
            % ----------------
            HEIGHT = (VEL^2)/(2*9.81)                                              % Calculate height using the velocity.
            
            % CALCULATE HEIGHT USING JUMP TIME
            % --------------------------------
            HEIGHT2 = 0.5*9.81*((JUMP_TIME)/2)^2                                   % Printed out for comparison.
            
            % SHOW HEIGTH AND IMPULSE IN GUI
            % -------------------------------- 
            app.ImpulseEditField.Value = int2str(IMP_MOM) + " Ns";
            app.HeightEditField.Value = num2str(HEIGHT) + " m";
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: LoadfileMenu
        function LoadfileMenuSelected(app, event)
            global signals WIDTH_FP LENGTH_FP FP_A_time FP_A_0 FP_A_1 FP_A_2 FP_A_3 FP_B_time FP_B_0 FP_B_1 FP_B_2 FP_B_3 FP_A_TOTAL FP_B_TOTAL CoP_A_X CoP_A_Y CoP_B_X CoP_B_Y;
            %------
            global FIND_SIGN_IND;                                                   % Find significant points after this index.
            global thresValueMax thresValueMin thresValueMin2 thresValueMax2;
            thresValueMax2=0; thresValueMin2=0; thresValueMin=0; thresValueMax=0;   % Initialisation of the treshold values, if it stays unknown the program would crash.
            FIND_SIGN_IND = 1;
            filename = uigetfile('*.txt');
            fID = fopen(filename)
            datacell = textscan(fID,'%f%f%f%f%f%f%f%f%f%f%f%f', 'HeaderLines', 3, 'CollectOutput', 1);
            fclose(fID);
            signals= datacell{1};
            
            signals(:,12);
           
            C = 406.831;
            nbits = 16;
            WIDTH_FP = 450;
            LENGTH_FP = 450;
            FP_A_Vfs0 = 2.00058;
            FP_A_Vfs1 = 2.00046;
            FP_A_Vfs2 = 2.00067;
            FP_A_Vfs3 = 2.00086;
            FP_B_Vfs0 = 1.99959;
            FP_B_Vfs1 = 1.99998;
            FP_B_Vfs2 = 1.99995;
            FP_B_Vfs3 = 1.99992;
            FP_A_time = signals(:,1);
            FP_A_0 = (signals(:,3))*C/(FP_A_Vfs0*(2^nbits - 1));
            FP_A_1 = (signals(:,4))*C/(FP_A_Vfs1*(2^nbits - 1));
            FP_A_2 = (signals(:,5))*C/(FP_A_Vfs2*(2^nbits - 1));
            FP_A_3 = (signals(:,6))*C/(FP_A_Vfs3*(2^nbits - 1));
            FP_A_TOTAL = FP_A_0 + FP_A_1 + FP_A_2 + FP_A_3;
            FP_B_time = signals(:,7);
            FP_B_0 = (signals(:,9))*C/(FP_B_Vfs0*(2^nbits - 1));
            FP_B_1 = (signals(:,10))*C/(FP_B_Vfs1*(2^nbits - 1));
            FP_B_2 = (signals(:,11))*C/(FP_B_Vfs2*(2^nbits - 1));
            FP_B_3 = (signals(:,12))*C/(FP_B_Vfs3*(2^nbits - 1));
            FP_B_TOTAL = FP_B_0 + FP_B_1 + FP_B_2 + FP_B_3;
            CoP_A_X = (WIDTH_FP/2).*((FP_A_1 + FP_A_2 - FP_A_1 - FP_A_3)./FP_A_TOTAL);
            CoP_A_Y = (LENGTH_FP/2).*((FP_A_0 + FP_A_1 - FP_A_2 - FP_A_3)./FP_A_TOTAL);
            CoP_B_X = (WIDTH_FP/2).*((FP_B_1 + FP_B_2 - FP_B_1 - FP_B_3)./FP_B_TOTAL);
            CoP_B_Y = (LENGTH_FP/2).*((FP_B_0 + FP_B_1 - FP_B_2 - FP_B_3)./FP_B_TOTAL); 
            hold(app.UIAxes, "off");
            plot(app.UIAxes,FP_A_time, FP_A_0);
            hold(app.UIAxes, "on");
            plot(app.UIAxes,FP_A_time, FP_A_1);
            plot(app.UIAxes,FP_A_time, FP_A_2);
            plot(app.UIAxes,FP_A_time, FP_A_3);
            plot(app.UIAxes,FP_A_time, FP_A_TOTAL);
            xlabel(app.UIAxes, "time[datapoints]");
            ylabel(app.UIAxes, "weight [kgf]");
            legend(app.UIAxes,"FP_A_0", "FP_A_1", "FP_A_2", "FP_A_3", "FP A SUM");
           
            
            hold(app.UIAxes2, "off");
            plot(app.UIAxes2,FP_B_time, FP_B_0);
            hold(app.UIAxes2, "on");
            plot(app.UIAxes2,FP_B_time, FP_B_1);
            plot(app.UIAxes2,FP_B_time, FP_B_2);
            plot(app.UIAxes2,FP_B_time, FP_B_3);
            plot(app.UIAxes2,FP_B_time, FP_B_TOTAL);
            xlabel(app.UIAxes2, "time[datapoints]");
            ylabel(app.UIAxes2, "weight [kgf]");
            legend(app.UIAxes2,"FP_B_0", "FP_B_1", "FP_B_2", "FP_B_3", "FP B SUM");
            
            hold(app.UIAxes3, "off");
            plot(app.UIAxes3,CoP_A_X, CoP_A_Y);
            xlabel(app.UIAxes3, "X[mm]");
            ylabel(app.UIAxes3, "Y[mm]"); 
           
            hold(app.UIAxes3_2, "off");
            plot(app.UIAxes3_2,CoP_B_X, CoP_B_Y);
            xlabel(app.UIAxes3_2, "X[mm]");
            ylabel(app.UIAxes3_2, "Y[mm]");
            
            
            DataselectDropDownValueChanged(app);
            CalculatepeaksButtonPushed(app);
            
            %--------------------------
            hold(app.UIAxes, "off");
        end

        % Value changed function: ColumnselectADropDown
        function ColumnselectADropDownValueChanged(app, event)
            global FP_A_time FP_A_0 FP_A_1 FP_A_2 FP_A_3 FP_A_TOTAL;

            value = app.ColumnselectADropDown.Value;
            switch value
                case '1'
                    %hold(app.UIAxes, "off");
                    plot(app.UIAxes,FP_A_time, FP_A_0);
                    hold(app.UIAxes, "on");
                    legend(app.UIAxes,"FP_A_0");
                    %----------------------------
                    hold(app.UIAxes, "off");
                case '2'
                    %hold(app.UIAxes, "off");
                    plot(app.UIAxes,FP_A_time, FP_A_1);
                    hold(app.UIAxes, "on");
                    legend(app.UIAxes,"FP_A_1");
                    %----------------------------
                    hold(app.UIAxes, "off");
                case '3'
                   % hold(app.UIAxes, "off");
                    plot(app.UIAxes,FP_A_time, FP_A_2);
                    hold(app.UIAxes, "on");
                    legend(app.UIAxes,"FP_A_2");
                    %----------------------------
                    hold(app.UIAxes, "off");
                case '4'
                    %hold(app.UIAxes, "off");
                    plot(app.UIAxes,FP_A_time, FP_A_3);
                    hold(app.UIAxes, "on");
                    legend(app.UIAxes,"FP_A_3");
                    %----------------------------
                    hold(app.UIAxes, "off");
                case '1-4'
                    %hold(app.UIAxes, "off");
                    plot(app.UIAxes,FP_A_time, FP_A_0);
                    hold(app.UIAxes, "on");
                    plot(app.UIAxes,FP_A_time, FP_A_1);
                    plot(app.UIAxes,FP_A_time, FP_A_2);
                    plot(app.UIAxes,FP_A_time, FP_A_3);
                    legend(app.UIAxes,"FP_A_0", "FP_A_1", "FP_A_2", "FP_A_3");
                    %----------------------------
                    hold(app.UIAxes, "off");
                case 'SUM'
                    %hold(app.UIAxes, "off");
                    plot(app.UIAxes,FP_A_time, FP_A_TOTAL);
                    legend(app.UIAxes,"FP A SUM");
                    %----------------------------
                    hold(app.UIAxes, "off");
                case 'ALL'
                    %hold(app.UIAxes, "off");
                    plot(app.UIAxes,FP_A_time, FP_A_0);
                    hold(app.UIAxes, "on");
                    plot(app.UIAxes,FP_A_time, FP_A_1);
                    plot(app.UIAxes,FP_A_time, FP_A_2);
                    plot(app.UIAxes,FP_A_time, FP_A_3);
                    plot(app.UIAxes,FP_A_time, FP_A_TOTAL);
                    legend(app.UIAxes,"FP_A_0", "FP_A_1", "FP_A_2", "FP_A_3", "FP A SUM");
                    %----------------------------
                    hold(app.UIAxes, "off");
                otherwise
            end
            
        end

        % Value changed function: ColumnselectBDropDown
        function ColumnselectBDropDownValueChanged(app, event)
            global FP_B_time FP_B_0 FP_B_1 FP_B_2 FP_B_3 FP_B_TOTAL;
            %i changed the position of the hold off for threshold purposes,
            %when plotting said threshold constant we need to execute a
            %hold on command, if we do not, once this part of the code
            %starts running, the thresholf will be wiped out due to the
            %hold off command at the beginning of every case
            
            %also changed axes mode to auto, for aesthetic purposes
            value = app.ColumnselectBDropDown.Value;
            switch value
                case '1'
                    %hold(app.UIAxes2, "off");
                    plot(app.UIAxes2,FP_B_time, FP_B_0);
                    legend(app.UIAxes2,"FP_B_0");
                    %----------------------------
                    hold(app.UIAxes2, "off");
                case '2'
                    %hold(app.UIAxes2, "off");
                    plot(app.UIAxes2,FP_B_time, FP_B_1);
                    legend(app.UIAxes2,"FP_B_1");
                    %----------------------------
                    hold(app.UIAxes2, "off");
                case '3'
                    %hold(app.UIAxes2, "off");
                    plot(app.UIAxes2,FP_B_time, FP_B_2);
                    legend(app.UIAxes2,"FP_B_2");
                    %----------------------------
                    hold(app.UIAxes2, "off");
                case '4'
                    %hold(app.UIAxes2, "off");
                    plot(app.UIAxes2,FP_B_time, FP_B_3);
                    legend(app.UIAxes2,"FP_B_3");
                    %----------------------------
                    hold(app.UIAxes2, "off");
                case '1-4'
                    %hold(app.UIAxes2, "off");
                    plot(app.UIAxes2,FP_B_time, FP_B_0);
                    hold(app.UIAxes2, "on");
                    plot(app.UIAxes2,FP_B_time, FP_B_1);
                    plot(app.UIAxes2,FP_B_time, FP_B_2);
                    plot(app.UIAxes2,FP_B_time, FP_B_3);
                    legend(app.UIAxes2,"FP_B_0", "FP_B_1", "FP_B_2", "FP_B_3");
                    %----------------------------
                    hold(app.UIAxes2, "off");
                case 'SUM'
                    %hold(app.UIAxes2, "off");
                    plot(app.UIAxes2,FP_B_time, FP_B_TOTAL);
                    legend(app.UIAxes2,"FP A SUM");
                    %----------------------------
                    hold(app.UIAxes2, "off");
                case 'ALL'
                    %hold(app.UIAxes2, "off");
                    plot(app.UIAxes2,FP_B_time, FP_B_0);
                    hold(app.UIAxes2, "on");
                    plot(app.UIAxes2,FP_B_time, FP_B_1);
                    plot(app.UIAxes2,FP_B_time, FP_B_2);
                    plot(app.UIAxes2,FP_B_time, FP_B_3);
                    plot(app.UIAxes2,FP_B_time, FP_B_TOTAL);
                    legend(app.UIAxes2,"FP_B_0", "FP_B_1", "FP_B_2", "FP_B_3", "FP B SUM");
                    %----------------------------
                    hold(app.UIAxes2, "off");
                otherwise
            end
        end

        % Callback function
        function PeakValleyThresholdASliderValueChanged(app, event)
            
%             smoothed_for_peak_detect = smooth(FP_A_TOTAL);
%             smoothed_for_peak_detect = smooth(smoothed_for_peak_detect);
            
%             if(size(FP_A_TOTAL_PK_LOCS)>0)
%                 if (FP_A_TOTAL_PK_LOCS(1) > FP_A_TOTAL_VL_LOCS(1))
%                     %VALLEY BEFORE PEAK
%                     valley = FP_A_TOTAL_VALLEYS(1);
%                     for i = 1:size(FP_A_TOTAL_VL_LOCS)
%                         if(valley > FP_A_TOTAL_VALLEYS(i))
%                             valley = FP_A_TOTAL_VALLEYS(i);
%                         end
%                         
%                     end
%                 else
%                     %PEAK BEFORE VALLEY
%                 end
%             end
        end

        % Callback function
        function PeakValleyThresholdBSliderValueChanged(app, event)
          
            
        end

        % Value changed function: DataselectDropDown
        function DataselectDropDownValueChanged(app, event)
            global FP_A_time FP_A_0 FP_A_1 FP_A_2 FP_A_3 FP_B_time FP_B_0 FP_B_1 FP_B_2 FP_B_3 FP_A_TOTAL FP_B_TOTAL thresValueMax thresValueMin
            global CoP_A_X CoP_A_Y CoP_B_X CoP_B_Y;
            global FP_A_TOTAL_PK_LOCS FP_A_TOTAL_PEAKS FP_A_TOTAL_VL_LOCS FP_A_TOTAL_VALLEYS FP_B_TOTAL_PK_LOCS FP_B_TOTAL_PEAKS FP_B_TOTAL_VL_LOCS FP_B_TOTAL_VALLEYS;
            value = app.DataselectDropDown.Value;
            switch value
                case 'A0'
                    app.UITable.Data = [FP_A_time FP_A_0];
                case 'A1'
                    app.UITable.Data = [FP_A_time FP_A_1];
                case 'A2'
                    app.UITable.Data = [FP_A_time FP_A_2];
                case 'A3'
                    app.UITable.Data = [FP_A_time FP_A_3];
                case 'A_SUM'
                    app.UITable.Data = [FP_A_time FP_A_TOTAL];
                case 'A_PEAKS'
                    app.UITable.Data = [FP_A_TOTAL_PK_LOCS FP_A_TOTAL_PEAKS];
                case 'A_VALLEYS'
                    app.UITable.Data = [FP_A_TOTAL_VL_LOCS FP_A_TOTAL_VALLEYS];
                case 'B0'
                    app.UITable.Data = [FP_B_time FP_B_0];
                case 'B1'
                    app.UITable.Data = [FP_B_time FP_B_1];
                case 'B2'
                    app.UITable.Data = [FP_B_time FP_B_2];
                case 'B3'
                    app.UITable.Data = [FP_B_time FP_B_3];
                case 'B_SUM'
                    app.UITable.Data = [FP_B_time FP_B_TOTAL];
                case 'B_PEAKS'
                    app.UITable.Data = [FP_B_TOTAL_PK_LOCS FP_B_TOTAL_PEAKS];
                case 'B_VALLEYS'
                    app.UITable.Data = [FP_B_TOTAL_VL_LOCS FP_B_TOTAL_VALLEYS];
            end
        end

        % Button pushed function: CalculatepeaksButton
        function CalculatepeaksButtonPushed(app, event)
             global FP_A_time FP_B_time;
            global FP_A_TOTAL FP_B_TOTAL;
            global FP_A_TOTAL_PEAKS FP_A_TOTAL_PK_LOCS FP_A_TOTAL_VALLEYS FP_A_TOTAL_VL_LOCS;
            global FP_B_TOTAL_PEAKS FP_B_TOTAL_PK_LOCS FP_B_TOTAL_VALLEYS FP_B_TOTAL_VL_LOCS;
            global thresValueMin2 thresValueMax2 thresValueMin thresValueMax;
            % This function will calculate the peaks. 
            % Peaks that are not outside of the thresholds given by the sliders will be thrown
            % away.
            
            a=300; % A local maximum will be calculated every a samples, this will assure meaningful peak points.
            hold(app.UIAxes, "on");
            hold(app.UIAxes2, "on");
            [FP_A_TOTAL_PEAKS, FP_A_TOTAL_PK_LOCS] = findpeaks(FP_A_TOTAL, "MinPeakDistance", a);
            locationsBelowMaxA=FP_A_TOTAL_PEAKS<thresValueMax;
            % We throw away points that are smaller than the desired
            % threshold.
            FP_A_TOTAL_PK_LOCS(locationsBelowMaxA)=[];
            FP_A_TOTAL_PEAKS(locationsBelowMaxA)=[];
            invert_A_TOTAL = -FP_A_TOTAL;
            [FP_A_TOTAL_VALLEYS, FP_A_TOTAL_VL_LOCS] = findpeaks(invert_A_TOTAL, "MinPeakDistance", a);
            FP_A_TOTAL_VALLEYS = -FP_A_TOTAL_VALLEYS;
            % Values not smaller than the minimum threshold value will be thrown
            % away.
            locationsAboveMinA=FP_A_TOTAL_VALLEYS>thresValueMin;
            FP_A_TOTAL_VL_LOCS(locationsAboveMinA)=[];
            FP_A_TOTAL_VALLEYS(locationsAboveMinA)=[];
            FP_A_TOTAL_PK_LOCS = FP_A_TOTAL_PK_LOCS + FP_A_time(1,1);
            FP_A_TOTAL_VL_LOCS = FP_A_TOTAL_VL_LOCS + FP_A_time(1,1);
            plot(app.UIAxes,FP_A_TOTAL_PK_LOCS, FP_A_TOTAL_PEAKS, "Marker",".", "LineStyle","none");
            plot(app.UIAxes,FP_A_TOTAL_VL_LOCS, FP_A_TOTAL_VALLEYS, "Marker",".", "LineStyle","none");
            xlim(app.UIAxes, [FP_A_time(1) length(FP_A_time)+FP_A_time(1)])
            legend(app.UIAxes,"FP_A_0", "FP_A_1", "FP_A_2", "FP_A_3", "FP A SUM", "FP A Peaks", "FP A Valleys");
            
            
            [FP_B_TOTAL_PEAKS, FP_B_TOTAL_PK_LOCS] = findpeaks(FP_B_TOTAL, "MinPeakDistance", a);
            locationsBelowMaxB=FP_B_TOTAL_PEAKS<thresValueMax2;
            FP_B_TOTAL_PK_LOCS(locationsBelowMaxB)=[];
            FP_B_TOTAL_PEAKS(locationsBelowMaxB)=[];
            invert_B_TOTAL = -FP_B_TOTAL;
            [FP_B_TOTAL_VALLEYS, FP_B_TOTAL_VL_LOCS] = findpeaks(invert_B_TOTAL, "MinPeakDistance", a);
            FP_B_TOTAL_VALLEYS = -FP_B_TOTAL_VALLEYS;
            locationsAboveMinB=FP_B_TOTAL_VALLEYS>thresValueMin2;
            FP_B_TOTAL_VL_LOCS(locationsAboveMinB)=[];
            FP_B_TOTAL_VALLEYS(locationsAboveMinB)=[];
            FP_B_TOTAL_PK_LOCS = FP_B_TOTAL_PK_LOCS + FP_B_time(1,1);
            FP_B_TOTAL_VL_LOCS = FP_B_TOTAL_VL_LOCS + FP_B_time(1,1);
            plot(app.UIAxes2,FP_B_TOTAL_PK_LOCS, FP_B_TOTAL_PEAKS, "Marker",".", "LineStyle","none");
            plot(app.UIAxes2,FP_B_TOTAL_VL_LOCS, FP_B_TOTAL_VALLEYS, "Marker",".", "LineStyle","none");
            xlim(app.UIAxes2, [FP_B_time(1) length(FP_B_time)+FP_B_time(1)])
            legend(app.UIAxes2,"FP_B_0", "FP_B_1", "FP_B_2", "FP_B_3", "FP B SUM", "FP B Peaks", "FP B Valleys");
        end

        % Button pushed function: SmoothAButton
        function SmoothAButtonPushed(app, event)
            global FP_A_0 FP_A_1 FP_A_2 FP_A_3 FP_A_TOTAL CoP_A_X CoP_A_Y WIDTH_FP LENGTH_FP
            FP_A_TOTAL = smoothdata(FP_A_TOTAL);
            ColumnselectADropDownValueChanged(app);
            CoP_A_X = (WIDTH_FP/2).*((FP_A_1 + FP_A_2 - FP_A_1 - FP_A_3)./FP_A_TOTAL);
            CoP_A_Y = (LENGTH_FP/2).*((FP_A_0 + FP_A_1 - FP_A_2 - FP_A_3)./FP_A_TOTAL);
            plot(app.UIAxes3,CoP_A_X, CoP_A_Y);
        end

        % Button pushed function: SmoothBButton
        function SmoothBButtonPushed(app, event)
            global FP_B_0 FP_B_1 FP_B_2 FP_B_3 FP_B_TOTAL CoP_B_X CoP_B_Y WIDTH_FP LENGTH_FP
            FP_B_TOTAL = smoothdata(FP_B_TOTAL);
            ColumnselectBDropDownValueChanged(app);
            CoP_B_X = (WIDTH_FP/2).*((FP_B_1 + FP_B_2 - FP_B_1 - FP_B_3)./FP_B_TOTAL);
            CoP_B_Y = (LENGTH_FP/2).*((FP_B_0 + FP_B_1 - FP_B_2 - FP_B_3)./FP_B_TOTAL);
            plot(app.UIAxes3_2,CoP_B_X, CoP_B_Y);
        end

        % Button pushed function: ResetdataAButton
        function ResetdataAButtonPushed(app, event)
            global FP_A_time FP_A_0 FP_A_1 FP_A_2 FP_A_3 FP_A_TOTAL CoP_A_X CoP_A_Y signals WIDTH_FP LENGTH_FP;
            
            C = 406.831;
            nbits = 16;
            FP_A_Vfs0 = 2.00058;
            FP_A_Vfs1 = 2.00046;
            FP_A_Vfs2 = 2.00067;
            FP_A_Vfs3 = 2.00086;
            
            FP_A_time = signals(:,1);
            FP_A_0 = signals(:,3)*C/(FP_A_Vfs0*(2^nbits - 1));
            FP_A_1 = signals(:,4)*C/(FP_A_Vfs1*(2^nbits - 1));
            FP_A_2 = signals(:,5)*C/(FP_A_Vfs2*(2^nbits - 1));
            FP_A_3 = signals(:,6)*C/(FP_A_Vfs3*(2^nbits - 1));
            FP_A_TOTAL = FP_A_0 + FP_A_1 + FP_A_2 + FP_A_3;

            CoP_A_X = (WIDTH_FP/2).*((FP_A_1 + FP_A_2 - FP_A_1 - FP_A_3)./FP_A_TOTAL);
            CoP_A_Y = (LENGTH_FP/2).*((FP_A_0 + FP_A_1 - FP_A_2 - FP_A_3)./FP_A_TOTAL);

            hold(app.UIAxes, "off");
            plot(app.UIAxes,FP_A_time, FP_A_0);
            hold(app.UIAxes, "on");
            plot(app.UIAxes,FP_A_time, FP_A_1);
            plot(app.UIAxes,FP_A_time, FP_A_2);
            plot(app.UIAxes,FP_A_time, FP_A_3);
            plot(app.UIAxes,FP_A_time, FP_A_TOTAL);
            xlabel(app.UIAxes, "time[datapoints]");
            ylabel(app.UIAxes, "weight [kgf]");
            legend(app.UIAxes,"FP_A_0", "FP_A_1", "FP_A_2", "FP_A_3", "FP A SUM");
            
            plot(app.UIAxes3,CoP_A_X, CoP_A_Y);
            xlabel(app.UIAxes3, "X[mm]");
            ylabel(app.UIAxes3, "Y[mm]"); 

            DataselectDropDownValueChanged(app);
            CalculatepeaksButtonPushed(app);
        end

        % Button pushed function: ResetdataBButton
        function ResetdataBButtonPushed(app, event)
            global FP_B_time FP_B_0 FP_B_1 FP_B_2 FP_B_3 FP_B_TOTAL CoP_B_X CoP_B_Y signals WIDTH_FP LENGTH_FP;
            
            C = 406.831;
            nbits = 16;
            FP_B_Vfs0 = 1.99959;
            FP_B_Vfs1 = 1.99998;
            FP_B_Vfs2 = 1.99995;
            FP_B_Vfs3 = 1.99992;
            
            FP_B_time = signals(:,7);
            FP_B_0 = signals(:,9)*C/(FP_B_Vfs0*(2^nbits - 1));
            FP_B_1 = signals(:,10)*C/(FP_B_Vfs1*(2^nbits - 1));
            FP_B_2 = signals(:,11)*C/(FP_B_Vfs2*(2^nbits - 1));
            FP_B_3 = signals(:,12)*C/(FP_B_Vfs3*(2^nbits - 1));
            FP_B_TOTAL = FP_B_0 + FP_B_1 + FP_B_2 + FP_B_3;
            CoP_B_X = (WIDTH_FP/2).*((FP_B_1 + FP_B_2 - FP_B_1 - FP_B_3)./FP_B_TOTAL);
            CoP_B_Y = (LENGTH_FP/2).*((FP_B_0 + FP_B_1 - FP_B_2 - FP_B_3)./FP_B_TOTAL); 
                        
            hold(app.UIAxes2, "off");
            plot(app.UIAxes2,FP_B_time, FP_B_0);
            hold(app.UIAxes2, "on");
            plot(app.UIAxes2,FP_B_time, FP_B_1);
            plot(app.UIAxes2,FP_B_time, FP_B_2);
            plot(app.UIAxes2,FP_B_time, FP_B_3);
            plot(app.UIAxes2,FP_B_time, FP_B_TOTAL);
            xlabel(app.UIAxes2, "time[datapoints]");
            ylabel(app.UIAxes2, "weight [kgf]");
            legend(app.UIAxes2,"FP_B_0", "FP_B_1", "FP_B_2", "FP_B_3", "FP B SUM");
            
            plot(app.UIAxes3_2,CoP_B_X, CoP_B_Y);
            xlabel(app.UIAxes3_2, "X[mm]");
            ylabel(app.UIAxes3_2, "Y[mm]");
            
            DataselectDropDownValueChanged(app);
            CalculatepeaksButtonPushed(app);
        end

        % Value changed function: maxthresholdSlider
        function maxthresholdSliderValueChanged(app, event)
            %----------------------------
            global thresValueMax FP_A_time thresValueMin;
            thresValueMax = app.maxthresholdSlider.Value;
            size(FP_A_time);
            cnst=ones(size(FP_A_time))';
            cnstMax=cnst*thresValueMax;
            cnstMin=cnst*thresValueMin;
            plot(app.UIAxes, FP_A_time, cnstMax, 'Color', 'red');
            hold (app.UIAxes, "on")
            plot(app.UIAxes, FP_A_time, cnstMin, 'Color', 'green');
            % Right now only the threshold value is visible, so we execute follwwing command to again check which case we want and combine it with the threshold 
            ColumnselectADropDownValueChanged(app, event);

        end

        % Value changed function: minthresholdSlider
        function minthresholdSliderValueChanged(app, event)
            %----------------------------
            global thresValueMin FP_A_time thresValueMax;           
            thresValueMin = app.minthresholdSlider.Value;
            cnst=ones(size(FP_A_time)).';
            cnstMax=cnst*thresValueMax;
            cnstMin=cnst*thresValueMin;
            plot(app.UIAxes, FP_A_time, cnstMin, 'Color', 'green');
            hold(app.UIAxes, "on")
            plot(app.UIAxes, FP_A_time, cnstMax, 'Color', 'red');
            ColumnselectADropDownValueChanged(app, event);
        end

        % Value changed function: maxthresholdSlider_2
        function maxthresholdSlider_2ValueChanged(app, event)
           %---------------------------- 
            global thresValueMin2 FP_B_time thresValueMax2;
            thresValueMax2 = app.maxthresholdSlider_2.Value;
            %constant vector of 1 with size of time axis, multiply by value
            %to get a constant line
            %time vector was taken from a matrix of which 1 colums
            %represented the time values, transpose the column to get a
            %vector, else misery, ditto for all other sliders
            cnst=ones(size(FP_B_time)).';
            cnstMax=cnst*thresValueMax2;
            cnstMin=cnst*thresValueMin2;
            plot(app.UIAxes2, FP_B_time, cnstMax, 'Color', 'red');
            hold(app.UIAxes2, "on")
            plot(app.UIAxes2, FP_B_time, cnstMin, 'Color', 'green');
            ColumnselectBDropDownValueChanged(app, event);
        end

        % Value changed function: minthresholdSlider_2
        function minthresholdSlider_2ValueChanged(app, event)
            %----------------------------
            global thresValueMin2 FP_B_time thresValueMax2;
            thresValueMin2 = app.minthresholdSlider_2.Value;
            cnst=ones(size(FP_B_time)).';
            cnstMax=cnst*thresValueMax2;
            cnstMin=cnst*thresValueMin2;
            plot(app.UIAxes2, FP_B_time, cnstMax, 'Color', 'red');
            hold(app.UIAxes2, "on")
            plot(app.UIAxes2, FP_B_time, cnstMin, 'Color', 'green');
            ColumnselectBDropDownValueChanged(app, event);
            
        end

        % Button pushed function: ExtractDatapoints
        function ExtractDatapointsButtonPushed(app, event)
            global FP_A_TOTAL FP_B_TOTAL;                                             % Total weight on force plate A & B.
            global FIND_SIGN_IND;                                                     % Find significant points after this index.
            
            fs = 1000;                                                                % Sampling rate.
            
            % STANDING STILL
            % --------------
            FP_TOTAL = FP_A_TOTAL + FP_B_TOTAL;                                       % Total weight on force plates.
            
            % CHECK START SLIDER
            % ------------------
            FP_TOTAL = FP_TOTAL(FIND_SIGN_IND:end);                                   % Cut the beginning of the signal depending on the value of the start slider.
            
            % TIME PARAMETERS
            % ---------------
            dt = 1/fs;                                                                % Time between samples.
            t = 0:dt:(length(FP_TOTAL)*dt) - dt;                                      % Time axis.
            
            % MEDIAN
            % ------
            MED = median(FP_TOTAL, 'all');                                            % Median of the signal.
            
            % FIND CHANGE POINTS
            % ------------------
            CHANGE_PTS = findchangepts(FP_TOTAL, 'MaxNumChanges', 10);                % Find change points in the signal.
                
            % STARTS JUMP MOVEMENT
            % --------------------
            tolerance = 4;                                                            % Minimum change from body mass.
            START_IND = find(FP_TOTAL < MED - tolerance, 1);                          % Relieves a bit of pressure on the plate (bending of knees).
            START_VAL = FP_TOTAL(START_IND);                                          % Weight at starting point.
            
            % STARTS POINT WITH CHANGE POINTS
            % -------------------------------
            START_IND2 = CHANGE_PTS(1);                                               % Alternative method for finding start.
            START_VAL2 = FP_TOTAL(START_IND2);                                        % Weight at starting point.
            
            % SUBJECT IS OFF GROUND
            % ---------------------
            [MIN_VAL, MIN_IND] = min(FP_TOTAL);                                       % Minimum weight on plates.
            SORT_CHANGE_PTS = mink(FP_TOTAL(CHANGE_PTS), 2);                          % Find the two smallest change points.
            % Find indexes of change points.
            SORT_PTS_IND = find(FP_TOTAL == SORT_CHANGE_PTS(1) | FP_TOTAL == SORT_CHANGE_PTS(end));
            OFF_GROUND = min(SORT_PTS_IND);                                           % Subject is off ground. 
            ON_GROUND = max(SORT_PTS_IND);                                            % Subject lands.
            JUMP_TIME = abs(t(ON_GROUND) - t(OFF_GROUND));                            % Subject's air time.
            
            % FORCE OF LANDING
            % ----------------
            [MAX_VAL, MAX_IND] = max(FP_TOTAL);                                       % Time and weight of maximum force.
            
            % RELEVANT PEAKS
            % --------------
            [PEAK_VAL, PEAK_IND] = findpeaks(FP_TOTAL, 'MinPeakProminence', 20, 'MinPeakDistance', 20);
            
            % RELEVANT VALLEYS
            % ----------------
            INV_FP = -FP_TOTAL;                                                       % We need the inverse of the signal.
            [VALLEY_VAL, VALLEY_IND] = findpeaks(INV_FP, 'MinPeakProminence', 20, 'MinPeakDistance', 20);
            VALLEY_VAL = -VALLEY_VAL;                                                 % Invert peak values to get valleys.
            
            % CALCULATE IMPULSE & JUMP HEIGHT
            % -------------------------------
            calculateHeight(app, FP_TOTAL, START_IND, OFF_GROUND, MED, JUMP_TIME, t);
            
            % GRAPHS
            % ------
            hold(app.UIAxes4, "off");
            plot(app.UIAxes4, t, FP_TOTAL, 'Color', [0, 0, 0], 'LineWidth', 1.5);
            hold(app.UIAxes4, "on");
            plot(app.UIAxes4, t(START_IND), START_VAL, 'r.', 'MarkerSize', 20);
            plot(app.UIAxes4, t(MAX_IND), MAX_VAL, 'r*', 'MarkerSize', 15);
            plot(app.UIAxes4, t(MIN_IND), MIN_VAL, 'r*', 'MarkerSize', 15);
            plot(app.UIAxes4, t(PEAK_IND), PEAK_VAL, 'y.', 'MarkerSize', 15);
            plot(app.UIAxes4, t(VALLEY_IND), VALLEY_VAL, 'b.', 'MarkerSize', 15);
            
            for i=1:length(CHANGE_PTS)
                if(CHANGE_PTS(i) == ON_GROUND || CHANGE_PTS(i) == OFF_GROUND || CHANGE_PTS(i) == START_IND2)
                    line(app.UIAxes4, [t(CHANGE_PTS(i)) t(CHANGE_PTS(i))], [(MIN_VAL - 10) (MAX_VAL + 10)], 'LineWidth', 1.25, 'Color', 'g', 'LineStyle', '--');
                end    
            end
            
            line(app.UIAxes4, [0 length(FP_TOTAL)], [MED MED], 'LineWidth', 1.25, 'Color', [0, 0.5, 0], 'LineStyle', '-.');
            
            % TEXT FIELDS
            % -----------
            app.JumpIntervalEditField.Value = num2str(JUMP_TIME) + " s";
            
            title(app.UIAxes4,'Total weight');
            xlabel(app.UIAxes4,'time[s]');
            ylabel(app.UIAxes4,'weight [kgf]');
            app.UIAxes4.XLim = [0 t(length(FP_TOTAL))];
            app.UIAxes4.YLim = [(MIN_VAL - 10) (MAX_VAL + 10)];
            legend(app.UIAxes4,"FP TOTAL", "Start", "Maximum", "Minimum", "Peaks", "Valleys", "Change Points");
           
        end

        % Value changed function: StartSlider
        function StartSliderValueChanged(app, event)
            global FP_A_TOTAL FP_B_TOTAL;                                           % Total weight on force plate A & B.
            global FIND_SIGN_IND;                                                   % Find significant points after this index.
            
            FP_TOTAL = FP_A_TOTAL + FP_B_TOTAL;                                     % Total weight on force plates.
            
            % SLIDER VALUE
            % ------------
            SLIDER_VALUE = app.StartSlider.Value;
            
            % CONVERT VALUE TO INDEX OF FP_TOTAL
            % ----------------------------------
            maxLength = length(FP_TOTAL)/2;                                         % Put a limit on the index.
            START_SEARCH = (maxLength / app.StartSlider.Limits(2)) * SLIDER_VALUE;
            START_SEARCH = round(START_SEARCH);                                     % Round to positive integer.
            FIND_SIGN_IND = START_SEARCH + 1;                                       % Index zero doesn't exist.
            
            % EXTRACT DATA POINTS AGAIN
            % -------------------------
            ExtractDatapointsButtonPushed(app,event);
        end

        % Button pushed function: RemoveoffsetButton
        function RemoveoffsetAButtonPushed(app, event)
            global signals m3 m4 m5 m6 FP_A_0 FP_A_1 FP_A_2 FP_A_3 FP_A_TOTAL;
            
            CHANGE_PTS = findchangepts(FP_A_TOTAL, 'MaxNumChanges', 10);        % Find change point
            
            [M, I] = min(FP_A_TOTAL);                                           % Find minimum value of signal 
            
            SORT_CHANGE_PTS = sort(CHANGE_PTS);                                 % Sort array of change points
            OFF_GROUND_I = find(SORT_CHANGE_PTS < I, 1,'last');                 % Start of jump interval (index).
            ON_GROUND_I = OFF_GROUND_I + 1;                                     % End of jump interval. Subject is landing (index).
            
            OFF_GROUND = SORT_CHANGE_PTS(OFF_GROUND_I);                         % Start of jump interval
            ON_GROUND = SORT_CHANGE_PTS(ON_GROUND_I);                           % End of jump interval
            
            C = 406.831;
            nbits = 16;
            FP_A_Vfs0 = 2.00058;
            FP_A_Vfs1 = 2.00046;
            FP_A_Vfs2 = 2.00067;
            FP_A_Vfs3 = 2.00086;
            
           
            % Calculate means of signals in the jump interval
            m3 = mean(signals((OFF_GROUND):(ON_GROUND), 3));
            m4 = mean(signals((OFF_GROUND):(ON_GROUND), 4));
            m5 = mean(signals((OFF_GROUND):(ON_GROUND), 5));
            m6 = mean(signals((OFF_GROUND):(ON_GROUND), 6));
            
            % Subtract calculated means from original signal to remove
            % offset
            FP_A_0 = (signals(:,3) - m3)*C/(FP_A_Vfs0*(2^nbits - 1));           %subtract offset here
            FP_A_1 = (signals(:,4) - m4)*C/(FP_A_Vfs1*(2^nbits - 1));           %subtract offset here
            FP_A_2 = (signals(:,5) - m5)*C/(FP_A_Vfs2*(2^nbits - 1));           %subtract offset here
            FP_A_3 = (signals(:,6) - m6)*C/(FP_A_Vfs3*(2^nbits - 1));           %subtract offset here
            FP_A_TOTAL = FP_A_0 + FP_A_1 + FP_A_2 + FP_A_3;
            
            ColumnselectADropDownValueChanged(app, event);
        end

        % Button pushed function: RemoveoffsetButton_2
        function RemoveoffsetBButtonPushed(app, event)
            global signals m9 m10 m11 m12 FP_B_0 FP_B_1 FP_B_2 FP_B_3 FP_B_TOTAL;
            
            CHANGE_PTS = findchangepts(FP_B_TOTAL, 'MaxNumChanges', 10)         % Find change point
            [M, I] = min(FP_B_TOTAL)                                            % Find minimum value of signal 
            
            SORT_CHANGE_PTS = sort(CHANGE_PTS)                                  % Sort array of change points
            OFF_GROUND_I = find(SORT_CHANGE_PTS < I, 1,'last')                  % Start of jump interval (index).
            ON_GROUND_I = OFF_GROUND_I + 1                                      % End of jump interval. Subject is landing (index).
            
            OFF_GROUND = SORT_CHANGE_PTS(OFF_GROUND_I)                        % Start of jump interval
            ON_GROUND = SORT_CHANGE_PTS(ON_GROUND_I)                          % End of jump interval
            
            C = 406.831;
            nbits = 16;
            FP_B_Vfs0 = 1.99959;
            FP_B_Vfs1 = 1.99998;
            FP_B_Vfs2 = 1.99995;
            FP_B_Vfs3 = 1.99992;
            
           
            % Calculate means of signals in the jump interval
            m9 = mean(signals((OFF_GROUND):(ON_GROUND), 9));
            m10 = mean(signals((OFF_GROUND):(ON_GROUND), 10));
            m11 = mean(signals((OFF_GROUND):(ON_GROUND), 11));
            m12 = mean(signals((OFF_GROUND):(ON_GROUND), 12));
            
            % Subtract calculated means from original signal to remove
            % offset
            FP_B_0 = (signals(:,9) - m9)*C/(FP_B_Vfs0*(2^nbits - 1));    %subtract offset here
            FP_B_1 = (signals(:,10) - m10)*C/(FP_B_Vfs1*(2^nbits - 1));  %subtract offset here
            FP_B_2 = (signals(:,11) - m11)*C/(FP_B_Vfs2*(2^nbits - 1));  %subtract offset here
            FP_B_3 = (signals(:,12) - m12)*C/(FP_B_Vfs3*(2^nbits - 1));  %subtract offset here
            FP_B_TOTAL = FP_B_0 + FP_B_1 + FP_B_2 + FP_B_3;
            
            ColumnselectBDropDownValueChanged(app, event);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
       
            % Open the HELP dialog
            app.HelpPopup = Help(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ForceplateProcessingtoolMainUIFigure = uifigure('Visible', 'off');
            app.ForceplateProcessingtoolMainUIFigure.Name = 'Forceplate Processing tool - Main';
            app.Menu = uimenu(app.ForceplateProcessingtoolMainUIFigure);
            app.Menu.Text = 'Menu';
            app.LoadfileMenu = uimenu(app.Menu);
            app.LoadfileMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadfileMenuSelected, true);
            app.LoadfileMenu.Text = 'Load file';
            app.UIAxes = uiaxes(app.ForceplateProcessingtoolMainUIFigure);
            title(app.UIAxes, 'Forces FP1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.ForceplateProcessingtoolMainUIFigure);
            title(app.UIAxes2, 'Forces FP2')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.ForceplateProcessingtoolMainUIFigure);
            title(app.UIAxes3, 'CoP FP1')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.UIAxes3_2 = uiaxes(app.ForceplateProcessingtoolMainUIFigure);
            title(app.UIAxes3_2, 'CoP FP2')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            app.UITable = uitable(app.ForceplateProcessingtoolMainUIFigure);
            app.UITable.ColumnName = {'t[s]'; 'F'};
            app.UITable.RowName = {};
            app.ColumnselectADropDownLabel = uilabel(app.ForceplateProcessingtoolMainUIFigure);
            app.ColumnselectADropDownLabel.Text = 'Column select A';
            app.ColumnselectADropDown = uidropdown(app.ForceplateProcessingtoolMainUIFigure);
            app.ColumnselectADropDown.Items = {'1', '2', '3', '4', '1-4', 'SUM', 'ALL'};
            app.ColumnselectADropDown.ValueChangedFcn = createCallbackFcn(app, @ColumnselectADropDownValueChanged, true);
            app.ColumnselectADropDown.Value = 'ALL';
            app.ColumnselectBDropDownLabel = uilabel(app.ForceplateProcessingtoolMainUIFigure);
            app.ColumnselectBDropDownLabel.Text = 'Column select B';
            app.ColumnselectBDropDown = uidropdown(app.ForceplateProcessingtoolMainUIFigure);
            app.ColumnselectBDropDown.Items = {'1', '2', '3', '4', '1-4', 'SUM', 'ALL'};
            app.ColumnselectBDropDown.ValueChangedFcn = createCallbackFcn(app, @ColumnselectBDropDownValueChanged, true);
            app.ColumnselectBDropDown.Value = 'ALL';
            app.DataselectDropDownLabel = uilabel(app.ForceplateProcessingtoolMainUIFigure);
            app.DataselectDropDownLabel.Text = 'Data select';
            app.DataselectDropDown = uidropdown(app.ForceplateProcessingtoolMainUIFigure);
            app.DataselectDropDown.Items = {'A0', 'A1', 'A2', 'A3', 'A_SUM', 'A_PEAKS', 'A_VALLEYS', 'B0', 'B1', 'B2', 'B3', 'B_SUM', 'B_PEAKS', 'B_VALLEYS'};
            app.DataselectDropDown.ValueChangedFcn = createCallbackFcn(app, @DataselectDropDownValueChanged, true);
            app.DataselectDropDown.Value = 'A0';
            app.CalculatepeaksButton = uibutton(app.ForceplateProcessingtoolMainUIFigure, 'push');
            app.CalculatepeaksButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatepeaksButtonPushed, true);
            app.CalculatepeaksButton.Text = 'Calculate peaks';
            app.SmoothAButton = uibutton(app.ForceplateProcessingtoolMainUIFigure, 'push');
            app.SmoothAButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothAButtonPushed, true);
            app.SmoothAButton.Text = 'Smooth A';
            app.SmoothBButton = uibutton(app.ForceplateProcessingtoolMainUIFigure, 'push');
            app.SmoothBButton.ButtonPushedFcn = createCallbackFcn(app, @SmoothBButtonPushed, true);
            app.SmoothBButton.Text = 'Smooth B';
            app.ResetdataAButton = uibutton(app.ForceplateProcessingtoolMainUIFigure, 'push');
            app.ResetdataAButton.ButtonPushedFcn = createCallbackFcn(app, @ResetdataAButtonPushed, true);
            app.ResetdataAButton.Text = 'Reset data A';
            app.ResetdataBButton = uibutton(app.ForceplateProcessingtoolMainUIFigure, 'push');
            app.ResetdataBButton.ButtonPushedFcn = createCallbackFcn(app, @ResetdataBButtonPushed, true);
            app.ResetdataBButton.Text = 'Reset data B';
            app.maxthresholdSliderLabel = uilabel(app.ForceplateProcessingtoolMainUIFigure);
            app.maxthresholdSliderLabel.Text = 'max threshold';
            app.maxthresholdSlider = uislider(app.ForceplateProcessingtoolMainUIFigure);
            app.maxthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @maxthresholdSliderValueChanged, true);
            app.minthresholdSliderLabel = uilabel(app.ForceplateProcessingtoolMainUIFigure);
            app.minthresholdSliderLabel.Text = 'min threshold';
            app.minthresholdSlider = uislider(app.ForceplateProcessingtoolMainUIFigure);
            app.minthresholdSlider.ValueChangedFcn = createCallbackFcn(app, @minthresholdSliderValueChanged, true);
            app.maxthresholdSlider_2Label = uilabel(app.ForceplateProcessingtoolMainUIFigure);
            app.maxthresholdSlider_2Label.Text = 'max threshold';
            app.maxthresholdSlider_2 = uislider(app.ForceplateProcessingtoolMainUIFigure);
            app.maxthresholdSlider_2.ValueChangedFcn = createCallbackFcn(app, @maxthresholdSlider_2ValueChanged, true);
            app.minthresholdSlider_2Label = uilabel(app.ForceplateProcessingtoolMainUIFigure);
            app.minthresholdSlider_2Label.Text = 'min threshold';
            app.minthresholdSlider_2 = uislider(app.ForceplateProcessingtoolMainUIFigure);
            app.minthresholdSlider_2.ValueChangedFcn = createCallbackFcn(app, @minthresholdSlider_2ValueChanged, true);
            app.ExtractDatapoints = uibutton(app.ForceplateProcessingtoolMainUIFigure, 'push');
            app.ExtractDatapoints.ButtonPushedFcn = createCallbackFcn(app, @ExtractDatapointsButtonPushed, true);
            app.ExtractDatapoints.Text = 'Significant Points';
            app.UIAxes4 = uiaxes(app.ForceplateProcessingtoolMainUIFigure);
            title(app.UIAxes4, 'Total weight')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.StartSlider = uislider(app.ForceplateProcessingtoolMainUIFigure);
            app.StartSlider.ValueChangedFcn = createCallbackFcn(app, @StartSliderValueChanged, true);
            app.JumpIntervalEditFieldLabel = uilabel(app.ForceplateProcessingtoolMainUIFigure);
            app.JumpIntervalEditFieldLabel.Text = 'Jump Interval';
            app.JumpIntervalEditField = uieditfield(app.ForceplateProcessingtoolMainUIFigure, 'text');
            app.JumpIntervalEditField.Editable = 'off';
            app.RemoveoffsetButton = uibutton(app.ForceplateProcessingtoolMainUIFigure, 'push');
            app.RemoveoffsetButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveoffsetAButtonPushed, true);
            app.RemoveoffsetButton.Text = 'Remove offset';
            app.RemoveoffsetButton_2 = uibutton(app.ForceplateProcessingtoolMainUIFigure, 'push');
            app.RemoveoffsetButton_2.ButtonPushedFcn = createCallbackFcn(app, @RemoveoffsetBButtonPushed, true);
            app.RemoveoffsetButton_2.Text = 'Remove offset';
            app.ImpulseEditFieldLabel = uilabel(app.ForceplateProcessingtoolMainUIFigure);
            app.ImpulseEditFieldLabel.Text = 'Impulse';
            app.ImpulseEditField = uieditfield(app.ForceplateProcessingtoolMainUIFigure, 'text');
            app.ImpulseEditField.Editable = 'off';
            app.HeightEditFieldLabel = uilabel(app.ForceplateProcessingtoolMainUIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.ForceplateProcessingtoolMainUIFigure, 'text');
            app.HeightEditField.Editable = 'off';
            app.HelpButton = uibutton(app.ForceplateProcessingtoolMainUIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.ForceplateProcessingtoolMainUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = forceplate_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ForceplateProcessingtoolMainUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ForceplateProcessingtoolMainUIFigure)
        end
    end
end